classdef four_tank_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FourTankProcess               matlab.ui.Figure
        SystemSettingPanel            matlab.ui.container.Panel
        StartStopSwitch               matlab.ui.control.Switch
        SimRealSwitch                 matlab.ui.control.Switch
        SimulatorspeedupLabel         matlab.ui.control.Label
        SimulatorSpeedUpDropDown      matlab.ui.control.DropDown
        PlotSettingPanel              matlab.ui.container.Panel
        TspanEditFieldLabel           matlab.ui.control.Label
        TspanEditField                matlab.ui.control.NumericEditField
        YminEditFieldLabel            matlab.ui.control.Label
        YminEditField                 matlab.ui.control.NumericEditField
        YmaxEditFieldLabel            matlab.ui.control.Label
        YmaxEditField                 matlab.ui.control.NumericEditField
        AxesResetButton               matlab.ui.control.Button
        SignalsPanel                  matlab.ui.container.Panel
        Ref1CheckBox                  matlab.ui.control.CheckBox
        tank3CheckBox                 matlab.ui.control.CheckBox
        tank1CheckBox                 matlab.ui.control.CheckBox
        ControlSignal1CheckBox        matlab.ui.control.CheckBox
        tank4CheckBox                 matlab.ui.control.CheckBox
        tank2CheckBox                 matlab.ui.control.CheckBox
        ControlSignal2CheckBox        matlab.ui.control.CheckBox
        Ref2CheckBox                  matlab.ui.control.CheckBox
        ControllerParameterPanel      matlab.ui.container.Panel
        RefSlider_1                   matlab.ui.control.Slider
        RefLabel_1                    matlab.ui.control.Label
        RefEditField_1                matlab.ui.control.NumericEditField
        InputSlider_1                 matlab.ui.control.Slider
        InputEditFieldLabel_1         matlab.ui.control.Label
        InputEditField_1              matlab.ui.control.NumericEditField
        ManualFeedbackSwitch          matlab.ui.control.Switch
        RefSlider_2                   matlab.ui.control.Slider
        RefLabel_2                    matlab.ui.control.Label
        RefEditField_2                matlab.ui.control.NumericEditField
        InputSlider_2                 matlab.ui.control.Slider
        InputEditFieldLabel_2         matlab.ui.control.Label
        InputEditField_2              matlab.ui.control.NumericEditField
        LoadFeedbackControllerButton  matlab.ui.control.Button
        FeedbackEditField             matlab.ui.control.EditField
        FeedbackControllerLoaded      matlab.ui.control.Lamp
        SelectControllerDirectoryButton  matlab.ui.control.Button
        DataAquisitionPanel           matlab.ui.container.Panel
        DataAquisition                matlab.ui.control.UIAxes
        Tank1GaugeLabel               matlab.ui.control.Label
        tank1Gauge                    matlab.ui.control.LinearGauge
        Tank2GaugeLabel               matlab.ui.control.Label
        tank2Gauge                    matlab.ui.control.LinearGauge
        tank1Overflow                 matlab.ui.control.Lamp
        tank2Overflow                 matlab.ui.control.Lamp
        tank3Overflow                 matlab.ui.control.Lamp
        Tank3GaugeLabel               matlab.ui.control.Label
        tank3Gauge                    matlab.ui.control.LinearGauge
        tank4Overflow                 matlab.ui.control.Lamp
        Tank4GaugeLabel               matlab.ui.control.Label
        tank4Gauge                    matlab.ui.control.LinearGauge
        SaveDataPanel                 matlab.ui.container.Panel
        SaveAsEditFieldLabel          matlab.ui.control.Label
        SaveAsEditField               matlab.ui.control.EditField
        SelectSaveDirectoryButton     matlab.ui.control.Button
        SaveDataButton                matlab.ui.control.Button
        SecondsToCaptureEditField     matlab.ui.control.NumericEditField
        TspanLabel                    matlab.ui.control.Label
        DisturbancesPanel             matlab.ui.container.Panel
        PourwaterTank1CheckBox        matlab.ui.control.CheckBox
        PourwaterTank2CheckBox        matlab.ui.control.CheckBox
        ExtraoutletTank3CheckBox      matlab.ui.control.CheckBox
        ExtraoutletTank4CheckBox      matlab.ui.control.CheckBox
        UncertaintyCheckBox           matlab.ui.control.CheckBox
        ModelNoEditFieldLabel         matlab.ui.control.Label
        ModelNoEditField              matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        % --- COMPONENTS: main --- %
        HW
        SW
        system
        PID
        Manual
        Feedback
        controller
        
        regpath
        savepath
        savename
        
        % --- COMPONENTS: timer --- %
        Timer
        AppIsRunning
        
        % --- COMPONENTS: plotting --- %
        figureOption
        ref1_plot       % handle: reference1 data
        ref2_plot       % handle: reference2 data
        tank1_plot     % handle: tank1 plot
        tank2_plot     % handle: tank2 plot
        tank3_plot     % handle: tank3 plot
        tank4_plot     % handle: tank4 plot
        controlSignal1_plot
        controlSignal2_plot
        %         Ppart_plot
        %         Ipart_plot
        %         Dpart_plot
        
        % --- COMPONENTS: tuner --- %
        %         ControllerTuner
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('tankClass')
            %             addpath('tankTuner')
            tankFcn.ProcessCheck(app);
            app.regpath = pwd;
            app.savepath = pwd;
            %             app.savename = 'fourTankOutput';
            tankFcn.ProcessPrepare(app);
            %             message = sprintf('No controller found. \n Save continious-time controller using: save regX.mat A B C D');
            %             uialert(app.FourTankProcess,message,'Warning',...
            %                     'Icon','warning');
            
                       
        end

        % Callback function
        function AntiwindupSwitchValueChanged(app, event)
            switch event.Value
                case 'On'
                    app.PID.AntiWindUp_flag = true;
                case 'Off'
                    app.PID.AntiWindUp_flag = false;
            end
        end

        % Button pushed function: AxesResetButton
        function AxesResetButtonPushed(app, event)
            stop(app.Timer)
            app.TspanEditField.Value = 1000;
            app.YminEditField.Value = 0;
            app.YmaxEditField.Value = 100;
            app.figureOption.axis_lim(1) = -1000;
            app.figureOption.NumSampleToPlot = 1000*20;
            app.figureOption.axis_lim(3) = 0;
            app.figureOption.axis_lim(4) = 100;
            tankFcn.PlotPrepare(app)
            start(app.Timer)
        end

        % Value changed function: ControlSignal1CheckBox
        function ControlSignal1CheckBoxValueChanged(app, event)
            if ~event.Value
                set(app.controlSignal1_plot,'YData',NaN(app.figureOption.NumSampleToPlot,1));
            end
        end

        % Value changed function: ControlSignal2CheckBox
        function ControlSignal2CheckBoxValueChanged(app, event)
            if ~event.Value
                set(app.controlSignal2_plot,'YData',NaN(app.figureOption.NumSampleToPlot,1));
            end            
        end

        % Close request function: FourTankProcess
        function FourTankProcessCloseRequest(app, event)
            tankFcn.ProcessStop(app);
            tankFcn.ProcessDelete(app);
            delete(app);
        end

        % Value changed function: InputEditField_2
        function InputEditField_2ValueChanged(app, event)
            app.InputEditField_2.Value = event.Value;
            app.InputSlider_2.Value = event.Value;
            app.Manual.controlSignal_before(1,2)=event.Value;
            
        end

        % Value changed function: InputSlider_2
        function InputSlider_2ValueChanged(app, event)
            app.InputEditField_2.Value = event.Value;
            app.InputSlider_2.Value = event.Value;
            app.Manual.controlSignal_before(1,2)=event.Value;
        end

        % Value changed function: InputEditField_1, InputSlider_1
        function InputValueChanged(app, event)
            app.InputEditField_1.Value = event.Value;
            app.InputSlider_1.Value = event.Value;
            app.Manual.controlSignal_before(1,1)=event.Value;
        end

        % Button pushed function: LoadFeedbackControllerButton
        function LoadFeedbackControllerButtonPushed(app, event)
            app.Feedback=ControllerFeedback([app.regpath,'\', app.FeedbackEditField.Value],...
                app.controller.Ts,app.controller.controlSignal_max);
            if (app.Feedback.controllerFound & app.Feedback.controllerCorrectDimensions)
                app.FeedbackControllerLoaded.Color = [0,1,0];
                tankFcn.Feedback_mode(app);
            elseif ~app.Feedback.controllerFound
                app.FeedbackControllerLoaded.Color = [0.65,0.65,0.65];
                message = sprintf('No controller found. \n Save continious-time controller using: \n save regX.mat A B C D');
                uialert(app.FourTankProcess,message,'Warning',...
                    'Icon','warning');
            elseif ~app.Feedback.controllerCorrectDimensions
                message = sprintf('Controller A B C D matrices have wrong dimensions');
                uialert(app.FourTankProcess,message,'Warning',...
                    'Icon','warning');
            end
        end

        % Value changed function: ManualFeedbackSwitch
        function ManualFeedbackSwitchValueChanged(app, event)
            switch event.Value
                case 'Manual'
                    tankFcn.Manual_mode(app);
                case 'Feedback'
                    tankFcn.Feedback_mode(app);
            end
        end

        % Value changed function: Ref1CheckBox
        function Ref1CheckBoxValueChanged(app, event)
            if ~event.Value
                set(app.ref1_plot,'YData',NaN(app.figureOption.NumSampleToPlot,1));
            end
        end

        % Value changed function: Ref2CheckBox
        function Ref2CheckBoxValueChanged(app, event)
            if ~event.Value
                set(app.ref2_plot,'YData',NaN(app.figureOption.NumSampleToPlot,1));
            end
        end

        % Value changed function: RefEditField_2, RefSlider_2
        function RefSlider_2ValueChanged(app, event)
            app.HW.ref(1,2) = event.Value;
            app.SW.ref(1,2) = event.Value;
            app.RefEditField_2.Value = event.Value;
            app.RefSlider_2.Value = event.Value;
        end

        % Value changed function: RefEditField_1, RefSlider_1
        function RefValueChanged(app, event)
            app.HW.ref(1,1) = event.Value;
            app.SW.ref(1,1) = event.Value;
            app.RefEditField_1.Value = event.Value;
            app.RefSlider_1.Value = event.Value;
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            if isa(app.SaveAsEditField.Value, 'char')
                app.savename = app.SaveAsEditField.Value;
                tankFcn.PlotCapture(app,[app.savepath,'\', app.savename])
            end            
        end

        % Button pushed function: SelectControllerDirectoryButton
        function SelectControllerDirectoryButtonPushed(app, event)
            path = uigetdir(app.regpath,'Controller Directory');
            if path == 0
                path =app.regpath;
            end
            app.regpath = path;
            disp(['Controller directory set to: ', path]) 
        end

        % Button pushed function: SelectSaveDirectoryButton
        function SelectSaveDirectoryButtonPushed(app, event)
            path = uigetdir(app.savepath,'Controller Directory');
            if path == 0
                path =app.savepath;
            end
            app.savepath = path;
            disp(['Save directory set to: ', path]) 
        end

        % Value changed function: SimRealSwitch
        function SimRealSwitchValueChanged(app, event)
            switch event.Value
                case 'Simulator'
                    tankFcn.SW_mode(app);
                case 'Real time'
                    tankFcn.HW_mode(app);
            end
        end

        % Value changed function: SimulatorSpeedUpDropDown
        function SimulatorSpeedUpDropDownValueChanged(app, event)
            switch event.Value
                case '1x'; scale = 5;
                case '2x'; scale = 4;
                case '3x'; scale = 3;
                case '4x'; scale = 2;
                case '5x'; scale = 1; % Disabled, this option gives problems on the watertanklab computers
                case '10x'; scale = 0.5;
            end
            if app.AppIsRunning
                stop(app.Timer)
                app.Timer.Period = app.system.Ts*(scale/5);
                start(app.Timer)
            end
        end

        % Value changed function: StartStopSwitch
        function StartStopSwitchCallback(app, event)
            app.StartStopSwitch.Enable = 'Off';
            switch event.Value
                case 'Start'
                    tankFcn.ProcessStart(app);
                case 'Stop'
                    tankFcn.ProcessStop(app);
            end
            app.StartStopSwitch.Enable = 'On';
        end

        % Value changed function: TspanEditField
        function TspanEditFieldValueChanged(app, event)
            if app.AppIsRunning
                stop(app.Timer)
                tankFcn.TspanReset(app,event)
                start(app.Timer)
            else
                tankFcn.TspanReset(app,event)
            end
            
        end

        % Value changed function: YmaxEditField
        function YmaxEditFieldValueChanged(app, event)
            app.figureOption.axis_lim(1,4)=event.Value;
            app.DataAquisition.YLim(2)=event.Value;
        end

        % Value changed function: YminEditField
        function YminEditFieldValueChanged(app, event)
            app.figureOption.axis_lim(1,3)=event.Value;
            app.DataAquisition.YLim(1)=event.Value;
        end

        % Value changed function: tank1CheckBox
        function tank1CheckBoxValueChanged(app, event)
            if ~event.Value
                set(app.tank1_plot,'YData',NaN(app.figureOption.NumSampleToPlot,1));
            end
        end

        % Value changed function: tank2CheckBox
        function tank2CheckBoxValueChanged(app, event)
            if ~event.Value
                set(app.tank2_plot,'YData',NaN(app.figureOption.NumSampleToPlot,1));
            end           
        end

        % Value changed function: tank3CheckBox
        function tank3CheckBoxValueChanged(app, event)
            if ~event.Value
                set(app.tank3_plot,'YData',NaN(app.figureOption.NumSampleToPlot,1));
            end
        end

        % Value changed function: tank4CheckBox
        function tank4CheckBoxValueChanged(app, event)
            if ~event.Value
                set(app.tank4_plot,'YData',NaN(app.figureOption.NumSampleToPlot,1));
            end            
        end

        % Callback function
        function PhaseSwitchValueChanged(app, event)
%             global model
            switch event.Value
                case 'Nonmin'
                    tankFcn.ProcessPrepare_phase(app);
                case 'Min'
                     tankFcn.ProcessPrepare(app);
            end
        end

        % Value changed function: PourwaterTank1CheckBox
        function PourwaterTank1CheckBoxValueChanged(app, event)
            value = app.PourwaterTank1CheckBox.Value;
            if value == 1
                app.system.state(end,1) = app.system.History(end,1) + 25;
            else 
                app.system.state(end,1) = app.system.History(end,1);
            end
        end

        % Value changed function: PourwaterTank2CheckBox
        function PourwaterTank2CheckBoxValueChanged(app, event)
            value = app.PourwaterTank2CheckBox.Value;
            if value == 1
                app.system.state(end,2) = app.system.History(end,2) + 25;
            else 
                app.system.state(end,2) = app.system.History(end,2);
            end
        end

        % Value changed function: ExtraoutletTank3CheckBox
        function ExtraoutletTank3CheckBoxValueChanged(app, event)
            value = app.ExtraoutletTank3CheckBox.Value;
            if value == 1
                app.system.model.alpha3 = app.system.model.alpha3 * 2;
                app.system.model.alpha13 = app.system.model.alpha13 * 2;
            else 
                app.system.model.alpha3 = app.system.model.alpha3 / 2;
                app.system.model.alpha13 = app.system.model.alpha13 / 2;
            end
        end

        % Value changed function: ExtraoutletTank4CheckBox
        function ExtraoutletTank4CheckBoxValueChanged(app, event)
            value = app.ExtraoutletTank4CheckBox.Value;
            if value == 1
                app.system.model.alpha4 = app.system.model.alpha4 * 2;
                app.system.model.alpha24 = app.system.model.alpha24 * 2;
            else 
                app.system.model.alpha4 = app.system.model.alpha4 / 2;
                app.system.model.alpha24 = app.system.model.alpha24 / 2;
            end
        end

        % Value changed function: UncertaintyCheckBox
        function UncertaintyCheckBoxValueChanged(app, event)
            value = app.UncertaintyCheckBox.Value;
            if value == 1
                   app.system.model.noise_level = app.system.model.noise_level + 0.5;
            else 
                   app.system.model.noise_level = 0;
            end
        end

        % Value changed function: ModelNoEditField
        function ModelNoEditFieldValueChanged(app, event)
            value = app.ModelNoEditField.Value;
            nr = mod(value,4)+1;
            distmat = [-1 1 -1 1; 1 -1 -1 1; 1 -1 1 -1; -1 1 1 -1];
            permutemat = 2*[1 1; 1 0; 0 1; 0 0]-1;
            scale=0.05;
            scale2=0.025;
            app.system.model.alphal = app.system.model.alpha1*(1+scale*distmat(nr,1));
            app.system.model.alpha2 = app.system.model.alpha2*(1+scale*distmat(nr,2));
            app.system.model.alpha3 = app.system.model.alpha3*(1+scale*distmat(nr,3));
            app.system.model.alpha4 = app.system.model.alpha4*(1+scale*distmat(nr,4));
            app.system.model.alpha13 = app.system.model.alpha13*(1+scale*distmat(nr,1));
            app.system.model.alpha24 = app.system.model.alpha24*(1+scale*distmat(nr,2));
            app.system.model.delta1 = app.system.model.delta1+scale2*permutemat(nr,1);
            app.system.model.delta2 = app.system.model.delta2+scale2*permutemat(nr,2);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FourTankProcess = uifigure;
            app.FourTankProcess.Name = 'Four tank process';
            app.FourTankProcess.CloseRequestFcn = createCallbackFcn(app, @FourTankProcessCloseRequest, true);
            app.SystemSettingPanel = uipanel(app.FourTankProcess);
            app.SystemSettingPanel.Title = 'System settings';
            app.StartStopSwitch = uiswitch(app.SystemSettingPanel, 'slider');
            app.StartStopSwitch.Items = {'Stop', 'Start'};
            app.StartStopSwitch.ValueChangedFcn = createCallbackFcn(app, @StartStopSwitchCallback, true);
            app.StartStopSwitch.Value = 'Stop';
            app.SimRealSwitch = uiswitch(app.SystemSettingPanel, 'slider');
            app.SimRealSwitch.Items = {'Simulator', 'Real time'};
            app.SimRealSwitch.ValueChangedFcn = createCallbackFcn(app, @SimRealSwitchValueChanged, true);
            app.SimRealSwitch.Value = 'Simulator';
            app.SimulatorspeedupLabel = uilabel(app.SystemSettingPanel);
            app.SimulatorspeedupLabel.Text = 'Simulator speed up';
            app.SimulatorSpeedUpDropDown = uidropdown(app.SystemSettingPanel);
            app.SimulatorSpeedUpDropDown.Items = {'1x', '2x', '3x', '4x', '5x', '10x'};
            app.SimulatorSpeedUpDropDown.ValueChangedFcn = createCallbackFcn(app, @SimulatorSpeedUpDropDownValueChanged, true);
            app.SimulatorSpeedUpDropDown.Value = '4x';
            app.PlotSettingPanel = uipanel(app.FourTankProcess);
            app.PlotSettingPanel.Title = 'Plot settings';
            app.TspanEditFieldLabel = uilabel(app.PlotSettingPanel);
            app.TspanEditFieldLabel.Text = 'T span';
            app.TspanEditField = uieditfield(app.PlotSettingPanel, 'numeric');
            app.TspanEditField.Limits = [10 1800];
            app.TspanEditField.ValueDisplayFormat = '%11.3g';
            app.TspanEditField.ValueChangedFcn = createCallbackFcn(app, @TspanEditFieldValueChanged, true);
            app.TspanEditField.Value = 400;
            app.YminEditFieldLabel = uilabel(app.PlotSettingPanel);
            app.YminEditFieldLabel.Text = 'Y min';
            app.YminEditField = uieditfield(app.PlotSettingPanel, 'numeric');
            app.YminEditField.Limits = [-200 200];
            app.YminEditField.ValueDisplayFormat = '%11.3g';
            app.YminEditField.ValueChangedFcn = createCallbackFcn(app, @YminEditFieldValueChanged, true);
            app.YminEditField.Value = -10;
            app.YmaxEditFieldLabel = uilabel(app.PlotSettingPanel);
            app.YmaxEditFieldLabel.Text = 'Y max';
            app.YmaxEditField = uieditfield(app.PlotSettingPanel, 'numeric');
            app.YmaxEditField.Limits = [-150 250];
            app.YmaxEditField.ValueDisplayFormat = '%11.3g';
            app.YmaxEditField.ValueChangedFcn = createCallbackFcn(app, @YmaxEditFieldValueChanged, true);
            app.YmaxEditField.Value = 120;
            app.AxesResetButton = uibutton(app.PlotSettingPanel, 'push');
            app.AxesResetButton.ButtonPushedFcn = createCallbackFcn(app, @AxesResetButtonPushed, true);
            app.AxesResetButton.Text = 'Axes reset';
            app.SignalsPanel = uipanel(app.PlotSettingPanel);
            app.SignalsPanel.Title = 'Signals';
            app.Ref1CheckBox = uicheckbox(app.SignalsPanel);
            app.Ref1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ref1CheckBoxValueChanged, true);
            app.Ref1CheckBox.Text = 'Ref1';
            app.tank3CheckBox = uicheckbox(app.SignalsPanel);
            app.tank3CheckBox.ValueChangedFcn = createCallbackFcn(app, @tank3CheckBoxValueChanged, true);
            app.tank3CheckBox.Text = 'Tank3';
            app.tank1CheckBox = uicheckbox(app.SignalsPanel);
            app.tank1CheckBox.ValueChangedFcn = createCallbackFcn(app, @tank1CheckBoxValueChanged, true);
            app.tank1CheckBox.Text = 'Tank1';
            app.tank1CheckBox.Value = true;
            app.ControlSignal1CheckBox = uicheckbox(app.SignalsPanel);
            app.ControlSignal1CheckBox.ValueChangedFcn = createCallbackFcn(app, @ControlSignal1CheckBoxValueChanged, true);
            app.ControlSignal1CheckBox.Text = 'Input1';
            app.ControlSignal1CheckBox.Value = true;
            app.tank4CheckBox = uicheckbox(app.SignalsPanel);
            app.tank4CheckBox.ValueChangedFcn = createCallbackFcn(app, @tank4CheckBoxValueChanged, true);
            app.tank4CheckBox.Text = 'Tank4';
            app.tank2CheckBox = uicheckbox(app.SignalsPanel);
            app.tank2CheckBox.ValueChangedFcn = createCallbackFcn(app, @tank2CheckBoxValueChanged, true);
            app.tank2CheckBox.Text = 'Tank2';
            app.tank2CheckBox.Value = true;
            app.ControlSignal2CheckBox = uicheckbox(app.SignalsPanel);
            app.ControlSignal2CheckBox.ValueChangedFcn = createCallbackFcn(app, @ControlSignal2CheckBoxValueChanged, true);
            app.ControlSignal2CheckBox.Text = 'Input2';
            app.ControlSignal2CheckBox.Value = true;
            app.Ref2CheckBox = uicheckbox(app.SignalsPanel);
            app.Ref2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ref2CheckBoxValueChanged, true);
            app.Ref2CheckBox.Text = 'Ref2';
            app.ControllerParameterPanel = uipanel(app.FourTankProcess);
            app.ControllerParameterPanel.Title = 'Controller settings';
            app.RefSlider_1 = uislider(app.ControllerParameterPanel);
            app.RefSlider_1.MajorTicks = [];
            app.RefSlider_1.MajorTickLabels = {};
            app.RefSlider_1.ValueChangedFcn = createCallbackFcn(app, @RefValueChanged, true);
            app.RefSlider_1.MinorTicks = [];
            app.RefLabel_1 = uilabel(app.ControllerParameterPanel);
            app.RefLabel_1.Text = 'Ref 1';
            app.RefEditField_1 = uieditfield(app.ControllerParameterPanel, 'numeric');
            app.RefEditField_1.Limits = [0 100];
            app.RefEditField_1.ValueDisplayFormat = '%11.3g';
            app.RefEditField_1.ValueChangedFcn = createCallbackFcn(app, @RefValueChanged, true);
            app.InputSlider_1 = uislider(app.ControllerParameterPanel);
            app.InputSlider_1.MajorTicks = [];
            app.InputSlider_1.MajorTickLabels = {};
            app.InputSlider_1.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.InputSlider_1.MinorTicks = [];
            app.InputEditFieldLabel_1 = uilabel(app.ControllerParameterPanel);
            app.InputEditFieldLabel_1.Text = 'Input 1';
            app.InputEditField_1 = uieditfield(app.ControllerParameterPanel, 'numeric');
            app.InputEditField_1.Limits = [0 100];
            app.InputEditField_1.ValueDisplayFormat = '%11.3g';
            app.InputEditField_1.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.ManualFeedbackSwitch = uiswitch(app.ControllerParameterPanel, 'slider');
            app.ManualFeedbackSwitch.Items = {'Manual', 'Feedback'};
            app.ManualFeedbackSwitch.ValueChangedFcn = createCallbackFcn(app, @ManualFeedbackSwitchValueChanged, true);
            app.ManualFeedbackSwitch.Value = 'Manual';
            app.RefSlider_2 = uislider(app.ControllerParameterPanel);
            app.RefSlider_2.MajorTicks = [];
            app.RefSlider_2.MajorTickLabels = {};
            app.RefSlider_2.ValueChangedFcn = createCallbackFcn(app, @RefSlider_2ValueChanged, true);
            app.RefSlider_2.MinorTicks = [];
            app.RefLabel_2 = uilabel(app.ControllerParameterPanel);
            app.RefLabel_2.Text = 'Ref 2';
            app.RefEditField_2 = uieditfield(app.ControllerParameterPanel, 'numeric');
            app.RefEditField_2.Limits = [0 100];
            app.RefEditField_2.ValueDisplayFormat = '%11.3g';
            app.RefEditField_2.ValueChangedFcn = createCallbackFcn(app, @RefSlider_2ValueChanged, true);
            app.InputSlider_2 = uislider(app.ControllerParameterPanel);
            app.InputSlider_2.MajorTicks = [];
            app.InputSlider_2.MajorTickLabels = {};
            app.InputSlider_2.ValueChangedFcn = createCallbackFcn(app, @InputSlider_2ValueChanged, true);
            app.InputSlider_2.MinorTicks = [];
            app.InputEditFieldLabel_2 = uilabel(app.ControllerParameterPanel);
            app.InputEditFieldLabel_2.Text = 'Input 2';
            app.InputEditField_2 = uieditfield(app.ControllerParameterPanel, 'numeric');
            app.InputEditField_2.Limits = [0 100];
            app.InputEditField_2.ValueDisplayFormat = '%11.3g';
            app.InputEditField_2.ValueChangedFcn = createCallbackFcn(app, @InputEditField_2ValueChanged, true);
            app.LoadFeedbackControllerButton = uibutton(app.ControllerParameterPanel, 'push');
            app.LoadFeedbackControllerButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFeedbackControllerButtonPushed, true);
            app.LoadFeedbackControllerButton.Text = 'Load Feedback Controller';
            app.FeedbackEditField = uieditfield(app.ControllerParameterPanel, 'text');
            app.FeedbackEditField.Value = 'regX.mat';
            app.FeedbackControllerLoaded = uilamp(app.ControllerParameterPanel);
            app.SelectControllerDirectoryButton = uibutton(app.ControllerParameterPanel, 'push');
            app.SelectControllerDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectControllerDirectoryButtonPushed, true);
            app.SelectControllerDirectoryButton.Text = 'Select Controller Directory';
            app.DataAquisitionPanel = uipanel(app.FourTankProcess);
            app.DataAquisitionPanel.Title = 'Data aquisition';
            app.DataAquisition = uiaxes(app.DataAquisitionPanel);
            xlabel(app.DataAquisition, 'Time [s]')
            ylabel(app.DataAquisition, 'Y [%]')
            app.Tank1GaugeLabel = uilabel(app.DataAquisitionPanel);
            app.Tank1GaugeLabel.Text = 'Tank1';
            app.tank1Gauge = uigauge(app.DataAquisitionPanel, 'linear');
            app.tank1Gauge.Limits = [0 120];
            app.tank1Gauge.MajorTicks = [0 20 40 60 80 100 120];
            app.tank1Gauge.MajorTickLabels = {'0', '20', '40', '60', '80', '100', ''};
            app.Tank2GaugeLabel = uilabel(app.DataAquisitionPanel);
            app.Tank2GaugeLabel.Text = 'Tank2';
            app.tank2Gauge = uigauge(app.DataAquisitionPanel, 'linear');
            app.tank2Gauge.Limits = [0 120];
            app.tank2Gauge.MajorTicks = [0 20 40 60 80 100 120];
            app.tank2Gauge.MajorTickLabels = {'0', '20', '40', '60', '80', '100', ''};
            app.tank1Overflow = uilamp(app.DataAquisitionPanel);
            app.tank2Overflow = uilamp(app.DataAquisitionPanel);
            app.tank3Overflow = uilamp(app.DataAquisitionPanel);
            app.Tank3GaugeLabel = uilabel(app.DataAquisitionPanel);
            app.Tank3GaugeLabel.Text = 'Tank3';
            app.tank3Gauge = uigauge(app.DataAquisitionPanel, 'linear');
            app.tank3Gauge.Limits = [0 120];
            app.tank3Gauge.MajorTicks = [0 20 40 60 80 100 120];
            app.tank3Gauge.MajorTickLabels = {'0', '20', '40', '60', '80', '100', ''};
            app.tank4Overflow = uilamp(app.DataAquisitionPanel);
            app.Tank4GaugeLabel = uilabel(app.DataAquisitionPanel);
            app.Tank4GaugeLabel.Text = 'Tank4';
            app.tank4Gauge = uigauge(app.DataAquisitionPanel, 'linear');
            app.tank4Gauge.Limits = [0 120];
            app.tank4Gauge.MajorTicks = [0 20 40 60 80 100 120];
            app.tank4Gauge.MajorTickLabels = {'0', '20', '40', '60', '80', '100', ''};
            app.SaveDataPanel = uipanel(app.FourTankProcess);
            app.SaveDataPanel.Title = 'Save Data';
            app.SaveAsEditFieldLabel = uilabel(app.SaveDataPanel);
            app.SaveAsEditFieldLabel.Text = 'Save As';
            app.SaveAsEditField = uieditfield(app.SaveDataPanel, 'text');
            app.SaveAsEditField.Value = 'fourTankOutput';
            app.SelectSaveDirectoryButton = uibutton(app.SaveDataPanel, 'push');
            app.SelectSaveDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSaveDirectoryButtonPushed, true);
            app.SelectSaveDirectoryButton.Text = 'Select Save Directory';
            app.SaveDataButton = uibutton(app.SaveDataPanel, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.SecondsToCaptureEditField = uieditfield(app.SaveDataPanel, 'numeric');
            app.SecondsToCaptureEditField.Limits = [10 1800];
            app.SecondsToCaptureEditField.Value = 400;
            app.TspanLabel = uilabel(app.SaveDataPanel);
            app.TspanLabel.Text = 'T span';
            app.DisturbancesPanel = uipanel(app.FourTankProcess);
            app.DisturbancesPanel.Title = 'Disturbances';
            app.PourwaterTank1CheckBox = uicheckbox(app.DisturbancesPanel);
            app.PourwaterTank1CheckBox.ValueChangedFcn = createCallbackFcn(app, @PourwaterTank1CheckBoxValueChanged, true);
            app.PourwaterTank1CheckBox.Text = 'Pour water Tank1 ';
            app.PourwaterTank2CheckBox = uicheckbox(app.DisturbancesPanel);
            app.PourwaterTank2CheckBox.ValueChangedFcn = createCallbackFcn(app, @PourwaterTank2CheckBoxValueChanged, true);
            app.PourwaterTank2CheckBox.Text = 'Pour water Tank2';
            app.ExtraoutletTank3CheckBox = uicheckbox(app.DisturbancesPanel);
            app.ExtraoutletTank3CheckBox.ValueChangedFcn = createCallbackFcn(app, @ExtraoutletTank3CheckBoxValueChanged, true);
            app.ExtraoutletTank3CheckBox.Text = 'Extra outlet Tank3';
            app.ExtraoutletTank4CheckBox = uicheckbox(app.DisturbancesPanel);
            app.ExtraoutletTank4CheckBox.ValueChangedFcn = createCallbackFcn(app, @ExtraoutletTank4CheckBoxValueChanged, true);
            app.ExtraoutletTank4CheckBox.Text = 'Extra outlet Tank4';
            app.UncertaintyCheckBox = uicheckbox(app.FourTankProcess);
            app.UncertaintyCheckBox.ValueChangedFcn = createCallbackFcn(app, @UncertaintyCheckBoxValueChanged, true);
            app.UncertaintyCheckBox.Text = 'Uncertainty';
            app.ModelNoEditFieldLabel = uilabel(app.FourTankProcess);
            app.ModelNoEditFieldLabel.Text = 'Model No.';
            app.ModelNoEditField = uieditfield(app.FourTankProcess, 'numeric');
            app.ModelNoEditField.ValueChangedFcn = createCallbackFcn(app, @ModelNoEditFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = four_tank_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FourTankProcess)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FourTankProcess)
        end
    end
end